classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        motionbluringButton          matlab.ui.control.Button
        BLPFButton                   matlab.ui.control.Button
        GLPFButton                   matlab.ui.control.Button
        ILPFButton                   matlab.ui.control.Button
        HighPassFilterLabel_2        matlab.ui.control.Label
        BHPFButton                   matlab.ui.control.Button
        GHPFButton                   matlab.ui.control.Button
        IHPFButton                   matlab.ui.control.Button
        HighPassFilterLabel          matlab.ui.control.Label
        SmoothingLabel               matlab.ui.control.Label
        ConvLabel                    matlab.ui.control.Label
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        GaussMaskButton              matlab.ui.control.Button
        MeanMaskButton               matlab.ui.control.Button
        Convwithmask4Button          matlab.ui.control.Button
        Convwithmask2Button          matlab.ui.control.Button
        Convwithmask3Button          matlab.ui.control.Button
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        FileInputnameEditField       matlab.ui.control.EditField
        FileInputnameEditFieldLabel  matlab.ui.control.Label
        Convwithmask1Button          matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)     
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Convwithmask1Button
        function Convwithmask1ButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            img_input = imread(filename);
            imshow(img_input, 'Parent', app.UIAxes);
            % mask1 = [1/16 2/16 1/16 ; 2/16 4/16 2/16 ; 1/16 2/16 1/16];
            mask1 = [1 2 1 ; 2 4 2 ; 1 2 1]/16;
            mask2 = [0 -1 0 ; -1 -4 -1 ; 0 -1 0];
            mask3 = [1 1 2 2 2 1 1 ; 1 2 2 4 2 2 1 ; 2 2 4 8 4 2 2 ; 2 4 8 16 8 4 2 ; 2 2 4 8 4 2 2 ; 1 2 2 4 2 2 1 ; 1 1 2 2 2 1 1] / 140 ;
            mask4 = [ -1 -1 -1 ; -1 17 -1 ; -1 -1 -1];
            img_result = uint8(my_rgb_conv(double(img_input), mask1));
            imshow(img_result, 'Parent', app.UIAxes_2);

            function result = my_rgb_conv(image, mask)
                [m, n, ~] = size(image);  % Get the dimensions and ignore the third dimension (color channels)
                [p, q] = size(mask);
                pad = floor((p - 1) / 2); % Padding
                result = zeros(m, n, 3);  % Initialize result for three color channels
                imagePadded = padarray(image, [pad, pad], 0, 'both');
            
                for c = 1:3 
                    for i = 1:m
                        for j = 1:n
                            region = imagePadded(i:i+p-1, j:j+q-1, c);
            
                            % Melakukan konvolusi
                            result(i, j, c) = sum(sum(region .* mask));
                        end
                    end
                end
            end
        end

        % Button pushed function: Convwithmask2Button
        function Convwithmask2ButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            img_input = imread(filename);
            imshow(img_input, 'Parent', app.UIAxes);
            % mask1 = [1/16 2/16 1/16 ; 2/16 4/16 2/16 ; 1/16 2/16 1/16];
            mask1 = [1 2 1 ; 2 4 2 ; 1 2 1]/16;
            mask2 = [0 -1 0 ; -1 -4 -1 ; 0 -1 0];
            mask3 = [1 1 2 2 2 1 1 ; 1 2 2 4 2 2 1 ; 2 2 4 8 4 2 2 ; 2 4 8 16 8 4 2 ; 2 2 4 8 4 2 2 ; 1 2 2 4 2 2 1 ; 1 1 2 2 2 1 1] / 140 ;
            mask4 = [ -1 -1 -1 ; -1 17 -1 ; -1 -1 -1];
            img_result = uint8(my_rgb_conv(double(img_input), mask2));
            imshow(img_result, 'Parent', app.UIAxes_2);
            
            function result = my_rgb_conv(image, mask)
                [m, n, ~] = size(image);  % Get the dimensions and ignore the third dimension (color channels)
                [p, q] = size(mask);
                pad = floor((p - 1) / 2); % Padding
                result = zeros(m, n, 3);  % Initialize result for three color channels
                imagePadded = padarray(image, [pad, pad], 0, 'both');
            
                for c = 1:3 
                    for i = 1:m
                        for j = 1:n
                            region = imagePadded(i:i+p-1, j:j+q-1, c);
            
                            % Melakukan konvolusi
                            result(i, j, c) = sum(sum(region .* mask));
                        end
                    end
                end
            end
        end

        % Button pushed function: Convwithmask3Button
        function Convwithmask3ButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            img_input = imread(filename);
            imshow(img_input, 'Parent', app.UIAxes);
            % mask1 = [1/16 2/16 1/16 ; 2/16 4/16 2/16 ; 1/16 2/16 1/16];
            mask1 = [1 2 1 ; 2 4 2 ; 1 2 1]/16;
            mask2 = [0 -1 0 ; -1 -4 -1 ; 0 -1 0];
            mask3 = [1 1 2 2 2 1 1 ; 1 2 2 4 2 2 1 ; 2 2 4 8 4 2 2 ; 2 4 8 16 8 4 2 ; 2 2 4 8 4 2 2 ; 1 2 2 4 2 2 1 ; 1 1 2 2 2 1 1] / 140 ;
            mask4 = [ -1 -1 -1 ; -1 17 -1 ; -1 -1 -1];
            img_result = uint8(my_rgb_conv(double(img_input), mask3));
            imshow(img_result, 'Parent', app.UIAxes_2);
            
            function result = my_rgb_conv(image, mask)
                [m, n, ~] = size(image);  % Get the dimensions and ignore the third dimension (color channels)
                [p, q] = size(mask);
                pad = floor((p - 1) / 2); % Padding
                result = zeros(m, n, 3);  % Initialize result for three color channels
                imagePadded = padarray(image, [pad, pad], 0, 'both');
            
                for c = 1:3 
                    for i = 1:m
                        for j = 1:n
                            region = imagePadded(i:i+p-1, j:j+q-1, c);
            
                            % Melakukan konvolusi
                            result(i, j, c) = sum(sum(region .* mask));
                        end
                    end
                end
            end
        end

        % Button pushed function: Convwithmask4Button
        function Convwithmask4ButtonPushed(app, event)
            filename = app.FileInputnameEditField.Value
            img_input = imread(filename);
            imshow(img_input, 'Parent', app.UIAxes);
            % mask1 = [1/16 2/16 1/16 ; 2/16 4/16 2/16 ; 1/16 2/16 1/16];
            mask1 = [1 2 1 ; 2 4 2 ; 1 2 1]/16;
            mask2 = [0 -1 0 ; -1 -4 -1 ; 0 -1 0];
            mask3 = [1 1 2 2 2 1 1 ; 1 2 2 4 2 2 1 ; 2 2 4 8 4 2 2 ; 2 4 8 16 8 4 2 ; 2 2 4 8 4 2 2 ; 1 2 2 4 2 2 1 ; 1 1 2 2 2 1 1] / 140 ;
            mask4 = [ -1 -1 -1 ; -1 17 -1 ; -1 -1 -1];
            img_result = uint8(my_rgb_conv(double(img_input), mask4));
            imshow(img_result, 'Parent', app.UIAxes_2);
            
            function result = my_rgb_conv(image, mask)
                [m, n, ~] = size(image);  % Get the dimensions and ignore the third dimension (color channels)
                [p, q] = size(mask);
                pad = floor((p - 1) / 2); % Padding
                result = zeros(m, n, 3);  % Initialize result for three color channels
                imagePadded = padarray(image, [pad, pad], 0, 'both');
            
                for c = 1:3 
                    for i = 1:m
                        for j = 1:n
                            region = imagePadded(i:i+p-1, j:j+q-1, c);
            
                            % Melakukan konvolusi
                            result(i, j, c) = sum(sum(region .* mask));
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Image')
            app.Convwithmask1Button = uibutton(app.UIFigure, 'push');
            app.Convwithmask1Button.ButtonPushedFcn = createCallbackFcn(app, @Convwithmask1ButtonPushed, true);
            app.Convwithmask1Button.Text = 'Conv with mask1';
            app.FileInputnameEditFieldLabel = uilabel(app.UIFigure);
            app.FileInputnameEditFieldLabel.Text = 'File Input name';
            app.FileInputnameEditField = uieditfield(app.UIFigure, 'text');
            app.FileInputnameEditField.Value = '1.jpg';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UIStuff', 'mask1.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UIStuff', 'mask3.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'UIStuff', 'mask2.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'UIStuff', 'mask4.png');
            app.Convwithmask3Button = uibutton(app.UIFigure, 'push');
            app.Convwithmask3Button.ButtonPushedFcn = createCallbackFcn(app, @Convwithmask3ButtonPushed, true);
            app.Convwithmask3Button.Text = 'Conv with mask3';
            app.Convwithmask2Button = uibutton(app.UIFigure, 'push');
            app.Convwithmask2Button.ButtonPushedFcn = createCallbackFcn(app, @Convwithmask2ButtonPushed, true);
            app.Convwithmask2Button.Text = 'Conv with mask2';
            app.Convwithmask4Button = uibutton(app.UIFigure, 'push');
            app.Convwithmask4Button.ButtonPushedFcn = createCallbackFcn(app, @Convwithmask4ButtonPushed, true);
            app.Convwithmask4Button.Text = 'Conv with mask4';
            app.MeanMaskButton = uibutton(app.UIFigure, 'push');
            app.MeanMaskButton.Text = 'Mean Mask';
            app.GaussMaskButton = uibutton(app.UIFigure, 'push');
            app.GaussMaskButton.Text = 'Gauss Mask';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 5;
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditField.Value = 2;
            app.ConvLabel = uilabel(app.UIFigure);
            app.ConvLabel.Text = 'Conv';
            app.SmoothingLabel = uilabel(app.UIFigure);
            app.SmoothingLabel.Text = 'Smoothing';
            app.HighPassFilterLabel = uilabel(app.UIFigure);
            app.HighPassFilterLabel.Text = 'HighPassFilter';
            app.IHPFButton = uibutton(app.UIFigure, 'push');
            app.IHPFButton.Text = 'IHPF';
            app.GHPFButton = uibutton(app.UIFigure, 'push');
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uibutton(app.UIFigure, 'push');
            app.BHPFButton.Text = 'BHPF';
            app.HighPassFilterLabel_2 = uilabel(app.UIFigure);
            app.HighPassFilterLabel_2.Text = 'HighPassFilter';
            app.ILPFButton = uibutton(app.UIFigure, 'push');
            app.ILPFButton.Text = 'ILPF';
            app.GLPFButton = uibutton(app.UIFigure, 'push');
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uibutton(app.UIFigure, 'push');
            app.BLPFButton.Text = 'BLPF';
            app.motionbluringButton = uibutton(app.UIFigure, 'push');
            app.motionbluringButton.Text = 'motion bluring';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end